classdef Main_Water_Quality_Correlation_Analysis_GUI_Model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterQualityCorrelationAnalysisGUIModelUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        CenterPanel               matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        TrendAnalysisButton       matlab.ui.control.Button
        EnvironmentDropDownLabel  matlab.ui.control.Label
        EnvironmentDropDown       matlab.ui.control.DropDown
        PCAAnalysisButton         matlab.ui.control.Button
        RDAAnalysisButton         matlab.ui.control.Button
        LoadCustomEnvironmentalFactorButton  matlab.ui.control.Button
        LoadCustomBiologicalFactorButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % initialize environmental related data
        envData = []
        envLabel = {};
        % initialize biological related data
        bioData = [];
        bioLabel = {};
        % initialize the segment style of different lines 
        semi = ['-^','-s','-*','-o','-+','-.','-x','-d','-v','->','-<','-p','-h','-^','-s','-*','-o','-+','-.','-x','-d','-v'];
    end
    
    methods (Access = private)
        
        function results = updateEnvironmentalData(app, fname)
            % For default file, read corresponding file
            if strcmp(fname,'Environmental factor of urban landscape water body supplemented by surface water.xlsx')
                data_0 = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx');
            else
                try
                    data = xlsread(fname);
                catch ME
                    % If problem reading file, display error message
                    uialert(app.WaterQualityCorrelationAnalysisGUIModelUIFigure, ME.message, 'File Error');
                    return;
                end            
            end 
            app.envData = data_0
            [num,txt,raw] = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C1:X1');
            app.envLablel = txt;
        end
        
        function results = updateBiologicalData(app, fname)
            % For default file, read corresponding file
            if strcmp(fname,'Pathogen index of urban landscape water body supplemented by surface water.xlsx')
                data = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx');
            else
                try
                    data = xlsread(fname);
                catch ME
                    % If problem reading file, display error message
                    uialert(app.WaterQualityCorrelationAnalysisGUIModelUIFigure, ME.message, 'File Error');
                    return;
                end            
            end
        end
        
        function resetBackground(app)
            app.TrendAnalysisButton.BackgroundColor='yellow' % Set yellow as default background color of button
            app.PCAAnalysisButton.BackgroundColor='yellow' % Set yellow as default background color of button
            app.RDAAnalysisButton.BackgroundColor='yellow' % Set yellow as default background color of button
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % when the GUI starts, Matlab load defult file automatically
            [num,txt,raw] = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C1:X1');
            app.envLabel = txt;
            app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx');
            [num,txt,raw] = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx','C1:J1')
            app.bioLabel  = txt;
            app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx')
            % initialize the color of the buttons
            resetBackground(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.WaterQualityCorrelationAnalysisGUIModelUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {514, 514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {379, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: 
        % LoadCustomEnvironmentalFactorButton
        function LoadCustomEnvironmentalFactorButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.xlsx; *.csv; *.txt; *.xls','All txt Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateEnvironmentalData(app, fname);
            end
        end

        % Button pushed function: LoadCustomBiologicalFactorButton
        function LoadCustomBiologicalFactorButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.xlsx; *.csv; *.txt; *.xls','All txt Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateBiologicalData(app, fname);
            end
        end

        % Button pushed function: PCAAnalysisButton
        function PCAAnalysisButtonPushed(app, event)
            % reset the color of buttons
            resetBackground(app)
            app.PCAAnalysisButton.BackgroundColor='red'
            % do PCA
            pca = f_pca(app.envData, 1, 2);
            f_pcaPlot(pca, [], app.envLabel, 0.05);
            pca = f_pca(X, 1, 2);
            pca = f_pca(y, 1, 2);
            s = {'A1' 'B2' 'A3' 'B4' 'A5' 'B6'};
            f_pcaPlot1(pca,['a'], ylabels, 0.2, 'tex');
            f_pcaPlot1(pca, s, ylabels, 0.2, 'tex');
        end

        % Value changed function: EnvironmentDropDown
        function EnvironmentDropDownValueChanged(app, event)
            % when the environment was changed by users, reread data
            [num,txt,raw] = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C1:X1');
            app.envLabel = txt;
            [num,txt,raw] = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx','C1:J1')
            app.bioLabel  = txt;
            value = app.EnvironmentDropDown.Value;
            switch value
                case 'Yueya River'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C2:X13');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx', 'C2:J13');
                case 'Lianhua River'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C14:X25');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx', 'C14:J25');
                case 'Changhong River'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C26:X37');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx', 'C26:J37');
                case 'All Data'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx')
            end
        end

        % Button pushed function: TrendAnalysisButton
        function TrendAnalysisButtonPushed(app, event)
            % when the button was clicked by users, reread data
            resetBackground(app)
            app.TrendAnalysisButton.BackgroundColor='red'
            value = app.EnvironmentDropDown.Value;
            switch value
                case 'Yueya River'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C2:X13');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx', 'C2:J13');
                case 'Lianhua River'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C14:X25');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx', 'C14:J25');
                case 'Changhong River'
                    app.envData = xlsread('Environmental factor of urban landscape water body supplemented by surface water.xlsx', 'C26:X37');
                    app.bioData = xlsread('Pathogen index of urban landscape water body supplemented by surface water.xlsx', 'C26:J37');
            end
            
            % draw the environmental data
            x = 1:12
            for j= 1:22
                plot(app.UIAxes, x, app.envData(1:12,j), app.semi(2*j-1:2*j));
                hold(app.UIAxes, 'on')
            end
            legend(app.UIAxes, app.envLabel, 'FontSize', 6)
            % Turn on the grid
            grid(app.UIAxes, 'on')
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            % Add title and axis labels
            title(app.UIAxes, 'Environmental Factor of Water')
            xlabel(app.UIAxes, 'Month')
            ylabel(app.UIAxes, 'Numerical Value')
            hold(app.UIAxes, 'off')
            
            % draw the biological data
            for j= 1:8
                plot(app.UIAxes2, x, app.envData(1:12,j), app.semi(2*j-1:2*j));
                hold(app.UIAxes2, 'on');
            end
            legend(app.UIAxes2, app.bioLabel, 'FontSize', 6)
            % Turn on the grid
            grid(app.UIAxes2, 'on')
            app.UIAxes2.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            % Add title and axis labels
            title(app.UIAxes2, 'Biological Factor of Water')
            xlabel(app.UIAxes2, 'Month')
            ylabel(app.UIAxes2, 'Numerical Value')
            hold(app.UIAxes2, 'off')
            
            % draw the environmental data
            x = 1:12
            for j= 1:22
                plot(app.UIAxes3, x, app.envData(1:12,j), app.semi(2*j-1:2*j));
                hold(app.UIAxes3, 'on')
            end
            legend(app.UIAxes3, app.envLabel, 'FontSize', 6)
            % Turn on the grid
            grid(app.UIAxes3, 'on')
            app.UIAxes3.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            
            app.UIAxes3.YLim = [1 100]
            % Add title and axis labels
            title(app.UIAxes3, 'Environmental Factor of Water')
            xlabel(app.UIAxes3, 'Month')
            ylabel(app.UIAxes3, 'Numerical Value')
            hold(app.UIAxes3, 'off')
            
            % draw the biological data
            for j= 1:8
                plot(app.UIAxes4, x, app.envData(1:12,j), app.semi(2*j-1:2*j));
                hold(app.UIAxes4, 'on');
            end
            legend(app.UIAxes4, app.bioLabel, 'FontSize', 6)
            % Turn on the grid
            grid(app.UIAxes4, 'on')
            app.UIAxes4.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];

            app.UIAxes4.YLim = [1 150]
            % Add title and axis labels
            title(app.UIAxes4, 'Biological Factor of Water')
            xlabel(app.UIAxes4, 'Month')
            ylabel(app.UIAxes4, 'Numerical Value')
            hold(app.UIAxes4, 'off')
        end

        % Button pushed function: RDAAnalysisButton
        function RDAAnalysisButtonPushed(app, event)
            % when the button was clicked, reset the color of the button
            resetBackground(app)
            app.RDAAnalysisButton.BackgroundColor='red'
            imshow('RDAResult.bmp');
            result = f_rda(app.bioData, app.envData, 0, 1000, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterQualityCorrelationAnalysisGUIModelUIFigure = uifigure('Visible', 'off');
            app.WaterQualityCorrelationAnalysisGUIModelUIFigure.Name = 'Water Quality Correlation Analysis GUI Model ';
            app.WaterQualityCorrelationAnalysisGUIModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.WaterQualityCorrelationAnalysisGUIModelUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.LeftPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.LeftPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.TrendAnalysisButton = uibutton(app.RightPanel, 'push');
            app.TrendAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @TrendAnalysisButtonPushed, true);
            app.TrendAnalysisButton.Text = 'Trend Analysis';
            app.EnvironmentDropDownLabel = uilabel(app.RightPanel);
            app.EnvironmentDropDownLabel.Text = 'Environment';
            app.EnvironmentDropDown = uidropdown(app.RightPanel);
            app.EnvironmentDropDown.Items = {'All Data', 'Yueya River', 'Lianhua River', 'Changhong River'};
            app.EnvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @EnvironmentDropDownValueChanged, true);
            app.EnvironmentDropDown.Value = 'All Data';
            app.PCAAnalysisButton = uibutton(app.RightPanel, 'push');
            app.PCAAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PCAAnalysisButtonPushed, true);
            app.PCAAnalysisButton.Text = 'PCA Analysis';
            app.RDAAnalysisButton = uibutton(app.RightPanel, 'push');
            app.RDAAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RDAAnalysisButtonPushed, true);
            app.RDAAnalysisButton.Text = 'RDA Analysis';
            app.LoadCustomEnvironmentalFactorButton = uibutton(app.RightPanel, 'push');
            app.LoadCustomEnvironmentalFactorButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomEnvironmentalFactorButtonPushed, true);
            app.LoadCustomEnvironmentalFactorButton.Text = 'Load Custom Environmental Factor';
            app.LoadCustomBiologicalFactorButton = uibutton(app.RightPanel, 'push');
            app.LoadCustomBiologicalFactorButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomBiologicalFactorButtonPushed, true);
            app.LoadCustomBiologicalFactorButton.Text = 'Load Custom Biological Factor';
            % Show the figure after all components are created
            app.WaterQualityCorrelationAnalysisGUIModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_Water_Quality_Correlation_Analysis_GUI_Model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterQualityCorrelationAnalysisGUIModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterQualityCorrelationAnalysisGUIModelUIFigure)
        end
    end
end